classdef GUI_montecarlo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        MAINPanel                       matlab.ui.container.Panel
        StartMONTECARLOframeworkButton  matlab.ui.control.Button
        loadButton                      matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        savetoworkspaceButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SUMMARYTab                      matlab.ui.container.Tab
        ConsoleTextAreaLabel            matlab.ui.control.Label
        ConsoleTextArea                 matlab.ui.control.TextArea
        clearButton                     matlab.ui.control.Button
        SimulationpropertiesPanel       matlab.ui.container.Panel
        tps1emkmEditFieldLabel          matlab.ui.control.Label
        tps1emkmEditField               matlab.ui.control.NumericEditField
        tps2emkmEditFieldLabel          matlab.ui.control.Label
        tps2emkmEditField               matlab.ui.control.NumericEditField
        LaunchTPSpowerOPT_2             matlab.ui.control.Button
        tps_clusterSwitchLabel          matlab.ui.control.Label
        tps_clusterSwitch               matlab.ui.control.Switch
        tps1ListBoxLabel                matlab.ui.control.Label
        tps1_clusterListBox             matlab.ui.control.ListBox
        tps2ListBoxLabel                matlab.ui.control.Label
        tps2_clusterListBox             matlab.ui.control.ListBox
        tps_lengthSwitchLabel           matlab.ui.control.Label
        tps_lengthSwitch                matlab.ui.control.Switch
        tps106EditFieldLabel            matlab.ui.control.Label
        tps1_numtrainsEditField         matlab.ui.control.NumericEditField
        tps206EditFieldLabel            matlab.ui.control.Label
        tps2_numtrainsEditField         matlab.ui.control.NumericEditField
        number_trainsSwitchLabel        matlab.ui.control.Label
        number_trainsSwitch             matlab.ui.control.Switch
        train_powerSwitchLabel          matlab.ui.control.Label
        train_powerSwitch               matlab.ui.control.Switch
        tps1pw1pwNLabel                 matlab.ui.control.Label
        tps1_trainpowerEditField        matlab.ui.control.EditField
        tps2pw1pw2Label                 matlab.ui.control.Label
        tps2_trainpowerEditField        matlab.ui.control.EditField
        train_pfSwitchLabel             matlab.ui.control.Label
        train_pfSwitch                  matlab.ui.control.Switch
        tps1pf1pfNLabel                 matlab.ui.control.Label
        tps1_trainpfEditField           matlab.ui.control.EditField
        tps2pf1pf2Label                 matlab.ui.control.Label
        tps2_trainpfEditField           matlab.ui.control.EditField
        train_posSwitchLabel            matlab.ui.control.Label
        train_posSwitch                 matlab.ui.control.Switch
        tps1pos1posNLabel               matlab.ui.control.Label
        tps1_trainposEditField          matlab.ui.control.EditField
        tps2pos1pos2Label               matlab.ui.control.Label
        tps2_trainposEditField          matlab.ui.control.EditField
        NOTEpowerinMWpfbetween05and1posbetween0and1Label  matlab.ui.control.Label
        ElectricdiagramTab              matlab.ui.container.Tab
        ElectricDiagramFig              matlab.ui.control.UIAxes
        NOTEhereisplottedthelastscenariosimulatedLabel  matlab.ui.control.Label
        ResultsAnalysisTab              matlab.ui.container.Tab
        InputparametersStatisticalAnalysisPanel  matlab.ui.container.Panel
        typeListBoxLabel                matlab.ui.control.Label
        statistic_analysis_typeListBox  matlab.ui.control.ListBox
        PerformStatisticalAnalysisButton  matlab.ui.control.Button
        OutputparametersPTDresultsAnalysisPanel  matlab.ui.container.Panel
        typeListBoxLabel_2              matlab.ui.control.Label
        ptd_analysis_typeListBox        matlab.ui.control.ListBox
        PerformResultsAnalysisButton    matlab.ui.control.Button
        SimulationclustersPanel         matlab.ui.container.Panel
        TPSlengthListBox_2Label         matlab.ui.control.Label
        TPSlengthListBox_2              matlab.ui.control.ListBox
        TPStypeclusterListBox_2Label    matlab.ui.control.Label
        TPStypeclusterListBox_2         matlab.ui.control.ListBox
        UpdateclusterButton_2           matlab.ui.control.Button
        objectsSwitch_2Label            matlab.ui.control.Label
        objectsSwitch_2                 matlab.ui.control.Switch
        underconstructionLabel          matlab.ui.control.Label
        ElectricModelParametersTab      matlab.ui.container.Tab
        TPScablePanel                   matlab.ui.container.Panel
        ResistanceOhmkmEditFieldLabel   matlab.ui.control.Label
        ResistanceOhmkmEditField        matlab.ui.control.NumericEditField
        ReactanceHkmEditFieldLabel      matlab.ui.control.Label
        ReactanceHkmEditField           matlab.ui.control.NumericEditField
        CapacitanceFkmEditFieldLabel    matlab.ui.control.Label
        CapacitanceFkmEditField         matlab.ui.control.NumericEditField
        cabledistancekmEditFieldLabel   matlab.ui.control.Label
        cabledistancekmEditField        matlab.ui.control.NumericEditField
        TPScatenaryPanel                matlab.ui.container.Panel
        ResistanceOhmkmEditField_2Label  matlab.ui.control.Label
        ResistanceOhmkmEditField_2      matlab.ui.control.NumericEditField
        ReactanceHkmEditField_2Label    matlab.ui.control.Label
        ReactanceHkmEditField_2         matlab.ui.control.NumericEditField
        CapacitanceFkmEditField_2Label  matlab.ui.control.Label
        CapacitanceFkmEditField_2       matlab.ui.control.NumericEditField
        PTDtransformerinverterPanel     matlab.ui.container.Panel
        TransformerXREditFieldLabel     matlab.ui.control.Label
        TransformerXREditField          matlab.ui.control.NumericEditField
        NominalPowerMWEditFieldLabel    matlab.ui.control.Label
        NominalPowerMWEditField         matlab.ui.control.NumericEditField
        TransformerironlossEditFieldLabel  matlab.ui.control.Label
        TransformerironlossEditField    matlab.ui.control.NumericEditField
        TransformertotallossEditFieldLabel  matlab.ui.control.Label
        TransformertotallossEditField   matlab.ui.control.NumericEditField
        TransformerconductionnominallossesMWEditFieldLabel  matlab.ui.control.Label
        TransformerconductionnominallossesMWEditField  matlab.ui.control.NumericEditField
        TransformerpermanentlossesMWEditFieldLabel  matlab.ui.control.Label
        TransformerpermanentlossesMWEditField  matlab.ui.control.NumericEditField
        inverterswitchinglossEditFieldLabel  matlab.ui.control.Label
        inverterswitchinglossEditField  matlab.ui.control.NumericEditField
        invertertotallossEditFieldLabel  matlab.ui.control.Label
        invertertotallossEditField      matlab.ui.control.NumericEditField
        recalculateButton               matlab.ui.control.Button
        InverterconductionnominallossesMWEditFieldLabel  matlab.ui.control.Label
        InverterconductionnominallossesMWEditField  matlab.ui.control.NumericEditField
        InverterpermanentlossesMWEditFieldLabel  matlab.ui.control.Label
        InverterpermanentlossesMWEditField  matlab.ui.control.NumericEditField
        bus100InvertertransfpermanentlossesMWEditFieldLabel  matlab.ui.control.Label
        bus100InvertertransfpermanentlossesMWEditField  matlab.ui.control.NumericEditField
        TransformerResistanceOhmEditFieldLabel  matlab.ui.control.Label
        TransformerResistanceOhmEditField  matlab.ui.control.NumericEditField
        TransformerReactancejOhmEditFieldLabel  matlab.ui.control.Label
        TransformerReactancejOhmEditField  matlab.ui.control.NumericEditField
        NominalCurrentAEditFieldLabel   matlab.ui.control.Label
        NominalCurrentAEditField        matlab.ui.control.NumericEditField
        InverterResistanceOhmEditFieldLabel  matlab.ui.control.Label
        InverterResistanceOhmEditField  matlab.ui.control.NumericEditField
        InverterReactancejOhmEditFieldLabel  matlab.ui.control.Label
        InverterReactancejOhmEditField  matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        TPSnominalvoltagePanel          matlab.ui.container.Panel
        VoltagekVEditFieldLabel         matlab.ui.control.Label
        VoltagekVEditField              matlab.ui.control.NumericEditField
        Updateallelectric_modelparametersButton  matlab.ui.control.Button
        PerunitPanel                    matlab.ui.container.Panel
        SbaseMVAEditFieldLabel          matlab.ui.control.Label
        SbaseMVAEditField               matlab.ui.control.NumericEditField
        VbasekVEditFieldLabel           matlab.ui.control.Label
        VbasekVEditField                matlab.ui.control.NumericEditField
        IbaseAEditFieldLabel            matlab.ui.control.Label
        IbaseAEditField                 matlab.ui.control.NumericEditField
        ZbaseOhmEditFieldLabel          matlab.ui.control.Label
        ZbaseOhmEditField               matlab.ui.control.NumericEditField
        updateButton                    matlab.ui.control.Button
        Monte_carloperformanceTab       matlab.ui.container.Tab
        launch_MCP                      matlab.ui.control.Button
        NumberofiterationsEditField_2Label_2  matlab.ui.control.Label
        Numberofiterations_MCP          matlab.ui.control.NumericEditField
        GaugeLabel                      matlab.ui.control.Label
        Gauge                           matlab.ui.control.LinearGauge
        UIAxes                          matlab.ui.control.UIAxes
        NumberofrunsLabel               matlab.ui.control.Label
        Numberoruns_MCP                 matlab.ui.control.NumericEditField
        updateButton_2                  matlab.ui.control.Button
        ConsoleTextArea_2Label          matlab.ui.control.Label
        Console_metrics_area            matlab.ui.control.TextArea
        MonteCarlonumberofsimulationsPanel  matlab.ui.container.Panel
        NumberofiterationsEditField_2Label  matlab.ui.control.Label
        NumberofiterationsEditField     matlab.ui.control.NumericEditField
        RUNOPTIMIZATIONPanel            matlab.ui.container.Panel
        LaunchTPSpowerOPT               matlab.ui.control.Button
        type_of_optimizationLabel       matlab.ui.control.Label
        type_of_optimizationListBox     matlab.ui.control.ListBox
        HELPPanel                       matlab.ui.container.Panel
        tps_power_optimizationPanel     matlab.ui.container.Panel
        ObjectivethepowerofbothTPSisbalancedLabel  matlab.ui.control.Label
        power_losses_optimizationPanel  matlab.ui.container.Panel
        ObjectivethepowerofbothTPSisbalancedLabel_2  matlab.ui.control.Label
        nz_voltage_optimizationPanel    matlab.ui.container.Panel
        ObjectivethephasevoltageinNZisbalancedLabel  matlab.ui.control.Label
        ExecutionpropertiesPanel        matlab.ui.container.Panel
        parallelismSwitchLabel          matlab.ui.control.Label
        parallelismSwitch               matlab.ui.control.Switch
        closeallButton                  matlab.ui.control.Button
        monitor_posEditFieldLabel       matlab.ui.control.Label
        monitor_posEditField            matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        monte_carlo_obj % Description
        number_of_iterations
        console1
        console_metrics
        metrics
    end

    methods (Access = private)
    
        function update_electrical_diagram(app)
            %app.ElectricDiagramFig.;
            ax = findobj(app.monte_carlo_obj.electric_diagram_fig_handler,'Type','Axes');
            copyobj(ax.Children, app.ElectricDiagramFig);
            %ax2 = findobj(app.ElectricDiagramFig,'Type','Axes');
            xlim(app.ElectricDiagramFig, [0 100]);
            ylim(app.ElectricDiagramFig, [0 200]);
            app.ElectricDiagramFig.XAxis.Visible='off';
            app.ElectricDiagramFig.YAxis.Visible='off';
            app.ElectricDiagramFig.Color = 'none';
            title(app.ElectricDiagramFig, '');
            close all;
            %console1{end}
            app.console1{end+1} = sprintf('Updated electrical Diagram \n');
            app.ConsoleTextArea.Value = app.console1;
        end
        function update_results_analysis(app)
            figure(app.monte_carlo_obj.ptd_results_fig_handler(1));
            fig_handler = gcf;  %%TODO: o problema é que não se consegue embutir subplots numa figura...
            ax = findobj(fig_handler,'Type','Axes');
            copyobj(ax.Children, app.ptd_results_fig_handler_fig2);
            
            app.console1{end+1} = sprintf('Updated update_results_analysis \n');
            app.ConsoleTextArea.Value = app.console1;
        end
        function number = process_string2array(string)
            
        end

        function save_object_to_workspace(app)
            app.console1{end+1} = sprintf('Saving framework to workspace (in "mc" object)\n');
            app.ConsoleTextArea.Value = app.console1;
            assignin('base', 'mc', app.monte_carlo_obj);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            app.monte_carlo_obj = MONTE_CARLO;
            app.console1{1} = sprintf('Object MONTE_CARLO created\n');
            app.ConsoleTextArea.Value = app.console1;
            app.number_of_iterations = app.NumberofiterationsEditField.Value;
            app.console1{end+1} = sprintf('Number of iterations updated: %i\n', app.number_of_iterations);
            app.ConsoleTextArea.Value = app.console1;
            app.monte_carlo_obj.figure_monitor_position = app.monitor_posEditField.Value;
            app.console1{end+1} = sprintf('Updated screen/monitor display to %i\n', app.monte_carlo_obj.figure_monitor_position);
            app.ConsoleTextArea.Value = app.console1;
            app.save_object_to_workspace();  %%% updates the workspace mc object
        end

        % Button pushed function: StartMONTECARLOframeworkButton
        function StartMONTECARLOframeworkButtonPushed(app, event)
            close all;
            app.monte_carlo_obj = MONTE_CARLO;
            app.number_of_iterations = 1000;
            app.console1{1} = sprintf('Object MONTE_CARLO created\n');
            app.ConsoleTextArea.Value = app.console1;
        end

        % Value changed function: NumberofiterationsEditField
        function NumberofiterationsEditFieldValueChanged(app, event)
            app.number_of_iterations = app.NumberofiterationsEditField.Value;
            app.console1{end+1} = sprintf('Number of iterations updated: %i\n', app.number_of_iterations);
            app.ConsoleTextArea.Value = app.console1;
        end

        % Button pushed function: PerformStatisticalAnalysisButton
        function PerformStatisticalAnalysisButtonPushed(app, event)
            app.monte_carlo_obj = app.monte_carlo_obj.perform_statistical_analysis(app.statistic_analysis_typeListBox.Value);
            %app.save_object_to_workspace();  %%% updates the workspace mc object
        end

        % Button pushed function: PerformResultsAnalysisButton
        function PerformResultsAnalysisButtonPushed(app, event)
            app.monte_carlo_obj = app.monte_carlo_obj.perform_ptd_analysis(app.ptd_analysis_typeListBox.Value);
            %app.save_object_to_workspace();  %%% updates the workspace mc object
            %app.update_results_analysis();
        end

        % Callback function
        function UpdateElectricDiagramButtonPushed(app, event)
              app.update_electrical_diagram();
        end

        % Button pushed function: LaunchTPSpowerOPT
        function LaunchRunOtimizationPushed(app, event)
            
            switch app.type_of_optimizationListBox.Value
                case 'tps_power_optimization'
                    app.console1{end+1} = sprintf('Launching tps_power_optimization for %i iterations\nPLEASE WAIT!!\n', app.number_of_iterations);
                    app.ConsoleTextArea.Value = app.console1;
                    app.monte_carlo_obj = app.monte_carlo_obj.eval_compensation('tps_power_optimization', app.number_of_iterations, app.parallelismSwitch.Value);
                    app.console1{end+1} = sprintf('Launched tps_power_optimization for %i iterations\n', app.number_of_iterations);
                    app.ConsoleTextArea.Value = app.console1;
                case 'power_losses_optimization'
                    app.console1{end+1} = sprintf('Launching power_losses_optimization for %i iterations\nPLEASE WAIT!!\n', app.number_of_iterations);
                    app.ConsoleTextArea.Value = app.console1;
                    app.monte_carlo_obj = app.monte_carlo_obj.eval_compensation('power_losses_optimization', app.number_of_iterations, app.parallelismSwitch.Value);
                    app.console1{end+1} = sprintf('Launched power_losses_optimization for %i iterations\n', app.number_of_iterations);
                    app.ConsoleTextArea.Value = app.console1;
                case 'nz_voltage_optimization'
                    app.console1{end+1} = sprintf('Launching nz_voltage_optimization for %i iterations\nPLEASE WAIT!!\n', app.number_of_iterations);
                    app.ConsoleTextArea.Value = app.console1;
                    app.monte_carlo_obj = app.monte_carlo_obj.eval_compensation('nz_voltage_optimization', app.number_of_iterations, app.parallelismSwitch.Value);
                    app.console1{end+1} = sprintf('Launched nz_voltage_optimization for %i iterations\n', app.number_of_iterations);
                    app.ConsoleTextArea.Value = app.console1;
            end
            app.update_electrical_diagram();
            app.save_object_to_workspace();  %%% updates the workspace mc object
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            app.monte_carlo_obj = app.monte_carlo_obj.load_monte_carlo();
            app.console1{end+1} = sprintf('Loading results\n');
            app.ConsoleTextArea.Value = app.console1;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.monte_carlo_obj = app.monte_carlo_obj.save_monte_carlo();
            app.console1{end+1} = sprintf('Saving results\n');
            app.ConsoleTextArea.Value = app.console1;
        end

        % Value changed function: monitor_posEditField
        function monitor_posEditFieldValueChanged(app, event)
            app.monte_carlo_obj.figure_monitor_position = app.monitor_posEditField.Value;
            app.console1{end+1} = sprintf('Updated screen/monitor display to %i\n', app.monte_carlo_obj.figure_monitor_position);
            app.ConsoleTextArea.Value = app.console1;
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.console1 = [];
            app.console1{1} = sprintf('Console Cleared\n');
            app.ConsoleTextArea.Value = app.console1;
        end

        % Button pushed function: closeallButton
        function closeallButtonPushed(app, event)
            close all;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete();
        end

        % Callback function
        function DunotingMenuSelected(app, event)
            %%du nothing
        end

        % Button pushed function: LaunchTPSpowerOPT_2
        function LaunchTPSpowerOPT_2ButtonPushed(app, event)
%             obj.random_status.tps_length = {'long','long'}; % {'random', 'random'}; %
%             obj.random_status.tps_length_value = [25 25];
            if strcmp(app.tps_lengthSwitch.Value , 'random')
                app.monte_carlo_obj.random_status.tps_length = {'random', 'random'};
                app.monte_carlo_obj.random_status.tps_length_value = {25 25};
            else
                app.monte_carlo_obj.random_status.tps_length_value{1} = app.tps1emkmEditField.Value;
                app.monte_carlo_obj.random_status.tps_length_value{2} = app.tps2emkmEditField.Value;
                if app.tps1emkmEditField.Value > 18
                    app.monte_carlo_obj.random_status.tps_length{1} = 'long';
                else
                    app.monte_carlo_obj.random_status.tps_length{1} = 'short';
                end
                if app.tps2emkmEditField.Value > 18
                    app.monte_carlo_obj.random_status.tps_length{2} = 'long';
                else
                    app.monte_carlo_obj.random_status.tps_length{2} = 'short';
                end
            end
            app.console1{end+1} = sprintf('Updated tps_length value tps1:%s (%i km) tps2:%s (%i km)\n', app.monte_carlo_obj.random_status.tps_length{1}, app.monte_carlo_obj.random_status.tps_length_value{1}, app.monte_carlo_obj.random_status.tps_length{2}, app.monte_carlo_obj.random_status.tps_length_value{2});
            app.ConsoleTextArea.Value = app.console1;
%             obj.random_status.tps_cluster = {'dense', 'dense'}; % {'random', 'random'}; %
            if strcmp(app.tps_clusterSwitch.Value , 'random')
                app.monte_carlo_obj.random_status.tps_cluster = {'random', 'random'};
            else
                app.monte_carlo_obj.random_status.tps_cluster{1} = app.tps1_clusterListBox.Value;
                app.monte_carlo_obj.random_status.tps_cluster{2} = app.tps2_clusterListBox.Value;
            end
            app.console1{end+1} = sprintf('Updated tps_cluster value tps1:%s tps2:%s\n', app.monte_carlo_obj.random_status.tps_cluster{1}, app.monte_carlo_obj.random_status.tps_cluster{2});
            app.ConsoleTextArea.Value = app.console1;
%             obj.random_status.num_trains_tps = {'fixed', 'fixed'}; % {'random', 'random'}; %
%             obj.random_status.num_trains_tps_value = [1 1];  %% NOTA: zero!
            if strcmp(app.number_trainsSwitch.Value , 'random')
                app.monte_carlo_obj.random_status.num_trains_tps = {'random', 'random'};
                app.monte_carlo_obj.random_status.num_trains_tps_value = {1 1};
            else
                app.monte_carlo_obj.random_status.num_trains_tps = {'fixed', 'fixed'};
                app.monte_carlo_obj.random_status.num_trains_tps_value{1} = app.tps1_numtrainsEditField.Value;
                app.monte_carlo_obj.random_status.num_trains_tps_value{2} = app.tps2_numtrainsEditField.Value;
            end
            app.console1{end+1} = sprintf('Updated num_trains value tps1:%s (%i trains) %s (%i trains)\n', app.monte_carlo_obj.random_status.num_trains_tps{1}, app.monte_carlo_obj.random_status.num_trains_tps_value{1}, app.monte_carlo_obj.random_status.num_trains_tps{2}, app.monte_carlo_obj.random_status.num_trains_tps_value{2});
            app.ConsoleTextArea.Value = app.console1;
%             obj.random_status.trains_power_tps = {'fixed', 'fixed'}; %{'random', 'random'}; % 
%             obj.random_status.trains_power_tps_value = [5 0];  %% em MW 
            if strcmp(app.train_powerSwitch.Value , 'random')
                app.monte_carlo_obj.random_status.trains_power_tps = {'random', 'random'};
                app.monte_carlo_obj.random_status.trains_power_tps_value = {[1] [1]};
            else
                app.monte_carlo_obj.random_status.trains_power_tps = {'fixed', 'fixed'};
                app.monte_carlo_obj.random_status.trains_power_tps_value{1} = str2num(convertStringsToChars(app.tps1_trainpowerEditField.Value));% TODO: necessário converter valor de string p/ vector numérico
                app.monte_carlo_obj.random_status.trains_power_tps_value{2} = str2num(convertStringsToChars(app.tps2_trainpowerEditField.Value));% TODO: necessário converter valor de string p/ vector numérico
            end
            app.console1{end+1} = sprintf('Updated trains_power value tps1:%s (%f MW) %s (%f MW)\n', app.monte_carlo_obj.random_status.trains_power_tps{1}, app.monte_carlo_obj.random_status.trains_power_tps_value{1}, app.monte_carlo_obj.random_status.trains_power_tps{2}, app.monte_carlo_obj.random_status.trains_power_tps_value{2});
            app.ConsoleTextArea.Value = app.console1;
%             obj.random_status.trains_pf_tps = {'fixed', 'fixed'}; % {'random', 'random'}; % 
%             obj.random_status.trains_pf_tps_value = [0.98 0.98];
            if strcmp(app.train_pfSwitch.Value , 'random')
                app.monte_carlo_obj.random_status.trains_pf_tps = {'random', 'random'};
                app.monte_carlo_obj.random_status.trains_pf_tps_value = {[0.98] [0.98]};
            else
                app.monte_carlo_obj.random_status.trains_pf_tps = {'fixed', 'fixed'};
                app.monte_carlo_obj.random_status.trains_pf_tps_value{1} = str2num(convertStringsToChars(app.tps1_trainpfEditField.Value));%; TODO: necessário converter valor de string p/ vector numérico
                app.monte_carlo_obj.random_status.trains_pf_tps_value{2} = str2num(convertStringsToChars(app.tps2_trainpfEditField.Value));%; TODO: necessário converter valor de string p/ vector numérico
            end
            app.console1{end+1} = sprintf('Updated trains_pf value tps1:%s (%.1f ind.) %s (%.1f ind.)\n', app.monte_carlo_obj.random_status.trains_pf_tps{1}, app.monte_carlo_obj.random_status.trains_pf_tps_value{1}, app.monte_carlo_obj.random_status.trains_pf_tps{2}, app.monte_carlo_obj.random_status.trains_pf_tps_value{2});
            app.ConsoleTextArea.Value = app.console1;
%             obj.random_status.trains_pos_tps = {'random', 'random'}; %% {'random', 'random'}; %
%             obj.random_status.trains_pos_tps_value = [0.5 0.5];  %% entre 0 e 1
            if strcmp(app.train_posSwitch.Value , 'random')
                app.monte_carlo_obj.random_status.trains_pos_tps = {'random', 'random'};
                app.monte_carlo_obj.random_status.trains_pos_tps_value = {[0.5] [0.5]};
            else
                app.monte_carlo_obj.random_status.trains_pos_tps = {'fixed', 'fixed'};
                app.monte_carlo_obj.random_status.trains_pos_tps_value{1} = str2num(convertStringsToChars(app.tps1_trainposEditField.Value));%; TODO: necessário converter valor de string p/ vector numérico
                app.monte_carlo_obj.random_status.trains_pos_tps_value{2} = str2num(convertStringsToChars(app.tps2_trainposEditField.Value));%; TODO: necessário converter valor de string p/ vector numérico
            end
            app.console1{end+1} = sprintf('Updated trains_pos value tps1:%s (%.1f km) %s (%.1f km)\n', app.monte_carlo_obj.random_status.trains_pos_tps{1}, app.monte_carlo_obj.random_status.trains_pos_tps_value{1}*app.monte_carlo_obj.random_status.tps_length_value{1}, app.monte_carlo_obj.random_status.trains_pos_tps{2}, app.monte_carlo_obj.random_status.trains_pos_tps_value{2}*app.monte_carlo_obj.random_status.tps_length_value{2});
            app.ConsoleTextArea.Value = app.console1;
            %%% UPDATE MC random status
            app.monte_carlo_obj.update_random_status();
        end

        % Button pushed function: recalculateButton
        function recalculateButtonPushed(app, event)
            app.TransformerconductionnominallossesMWEditField.Value = app.NominalPowerMWEditField.Value*app.TransformertotallossEditField.Value*0.01*(1-app.TransformerironlossEditField.Value*0.01);
            app.TransformerpermanentlossesMWEditField.Value = app.NominalPowerMWEditField.Value*app.TransformertotallossEditField.Value*0.01*(app.TransformerironlossEditField.Value*0.01);
            app.InverterconductionnominallossesMWEditField.Value = app.NominalPowerMWEditField.Value*app.invertertotallossEditField.Value*0.01*(1-app.inverterswitchinglossEditField.Value*0.01);
            app.InverterpermanentlossesMWEditField.Value = app.NominalPowerMWEditField.Value*app.invertertotallossEditField.Value*0.01*(app.inverterswitchinglossEditField.Value*0.01);
            app.bus100InvertertransfpermanentlossesMWEditField.Value = app.InverterpermanentlossesMWEditField.Value + app.TransformerpermanentlossesMWEditField.Value;
            
            app.NominalCurrentAEditField.Value = (app.NominalPowerMWEditField.Value.*1e6)/(app.VoltagekVEditField.Value.*1e3);
            app.TransformerResistanceOhmEditField.Value = (app.TransformerconductionnominallossesMWEditField.Value.*1e6) / (app.NominalCurrentAEditField.Value^2);
            app.TransformerReactancejOhmEditField.Value = app.TransformerResistanceOhmEditField.Value*app.TransformerXREditField.Value;
            
            app.InverterResistanceOhmEditField.Value = (app.InverterconductionnominallossesMWEditField.Value.*1e6) / (app.NominalCurrentAEditField.Value^2);
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            app.VbasekVEditField.Value = app.VoltagekVEditField.Value;
            app.IbaseAEditField.Value = (app.SbaseMVAEditField.Value.*1e6)/(app.VbasekVEditField.Value.*1e3);
            app.ZbaseOhmEditField.Value = (app.VbasekVEditField.Value.*1e3)/app.IbaseAEditField.Value;
        end

        % Button pushed function: 
        % Updateallelectric_modelparametersButton
        function Updateallelectric_modelparametersButtonPushed(app, event)
            app.monte_carlo_obj.electric_parameters.cable.resistance = app.ResistanceOhmkmEditField.Value;
            app.monte_carlo_obj.electric_parameters.cable.reactance =  app.ReactanceHkmEditField.Value;
            app.monte_carlo_obj.electric_parameters.cable.capacitance = app.CapacitanceFkmEditField.Value;
            app.monte_carlo_obj.electric_parameters.cable.distance = app.cabledistancekmEditField.Value;
            
            app.monte_carlo_obj.electric_parameters.catenary.resistance = app.ResistanceOhmkmEditField_2.Value;
            app.monte_carlo_obj.electric_parameters.catenary.reactance = app.ReactanceHkmEditField_2.Value;
            app.monte_carlo_obj.electric_parameters.catenary.capacitance = app.CapacitanceFkmEditField_2.Value;
            
            app.monte_carlo_obj.electric_parameters.nominal_voltage = app.VoltagekVEditField.Value;
            
            app.monte_carlo_obj.electric_parameters.ptd.nominal_power = app.NominalPowerMWEditField.Value; 
            app.monte_carlo_obj.electric_parameters.ptd.transformer_impedance = app.TransformerResistanceOhmEditField.Value + 1i*app.TransformerReactancejOhmEditField.Value;
            app.monte_carlo_obj.electric_parameters.ptd.permanent_losses = app.bus100InvertertransfpermanentlossesMWEditField.Value;
            app.monte_carlo_obj.electric_parameters.ptd.inverter_impedance = app.InverterResistanceOhmEditField.Value + 1i*app.InverterReactancejOhmEditField.Value;
        end

        % Button pushed function: savetoworkspaceButton
        function savetoworkspaceButtonPushed(app, event)
            app.save_object_to_workspace();  %%% updates the workspace mc object
        end

        % Button pushed function: launch_MCP
        function launch_MCPButtonPushed(app, event)
            %%%loop 10 times
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');
            grid(app.UIAxes,'minor');
            app.console1{end+1} = sprintf('Starting Monte Carlo Performance Evaluation \n');
            app.ConsoleTextArea.Value = app.console1;
            app.console_metrics{end+1} = sprintf('it;mu;sigma;\n');
            app.Console_metrics_area.Value = app.console_metrics;
            for it=1:app.Numberoruns_MCP.Value
                app.Gauge.Value = it;
                pause(1);
                app.monte_carlo_obj = app.monte_carlo_obj.eval_compensation('tps_power_optimization', app.number_of_iterations, app.parallelismSwitch.Value);
                
                app.monte_carlo_obj = app.monte_carlo_obj.perform_ptd_analysis('metrics');
                app.metrics = [app.metrics app.monte_carlo_obj.results.all_metrics];
                plot(app.UIAxes, it, app.monte_carlo_obj.results.all_metrics.mu, 'o');
                app.console1{end+1} = sprintf('tested N cases: mu = %.2f\n', app.monte_carlo_obj.results.all_metrics.mu);
                app.ConsoleTextArea.Value = app.console1;
                app.console_metrics{end+1} = sprintf('%i;%.4f;%.4f;\n', it, app.monte_carlo_obj.results.all_metrics.mu, app.monte_carlo_obj.results.all_metrics.sigma);
                app.Console_metrics_area.Value = app.console_metrics;
                file2save = app.monte_carlo_obj;
                save(strcat('final_results/final_results_', num2str(it)), 'file2save', '-v7.3');
                app.console1{end+1} = sprintf('saved to final_results/final_results_%i\n', it);
                app.ConsoleTextArea.Value = app.console1;
            end
        end

        % Button pushed function: updateButton_2
        function updateButton_2Pushed(app, event)
            app.Gauge.Limits(2) = app.Numberoruns_MCP.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure = uifigure;
            app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure.Name = 'MONTE CARLO evaluation for railway Power Transfer Device';
            app.FileMenu = uimenu(app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.MAINPanel = uipanel(app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure);
            app.MAINPanel.Title = 'MAIN';
            app.StartMONTECARLOframeworkButton = uibutton(app.MAINPanel, 'push');
            app.StartMONTECARLOframeworkButton.ButtonPushedFcn = createCallbackFcn(app, @StartMONTECARLOframeworkButtonPushed, true);
            app.StartMONTECARLOframeworkButton.Text = 'Start MONTE CARLO framework';
            app.loadButton = uibutton(app.MAINPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.saveButton = uibutton(app.MAINPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.savetoworkspaceButton = uibutton(app.MAINPanel, 'push');
            app.savetoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @savetoworkspaceButtonPushed, true);
            app.savetoworkspaceButton.Text = 'save to workspace';
            app.TabGroup = uitabgroup(app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure);
            app.SUMMARYTab = uitab(app.TabGroup);
            app.SUMMARYTab.Title = 'SUMMARY';
            app.ConsoleTextAreaLabel = uilabel(app.SUMMARYTab);
            app.ConsoleTextAreaLabel.Text = 'Console';
            app.ConsoleTextArea = uitextarea(app.SUMMARYTab);
            app.clearButton = uibutton(app.SUMMARYTab, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.SimulationpropertiesPanel = uipanel(app.SUMMARYTab);
            app.SimulationpropertiesPanel.Title = 'Simulation properties';
            app.tps1emkmEditFieldLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps1emkmEditFieldLabel.Text = 'tps1 (em km)';
            app.tps1emkmEditField = uieditfield(app.SimulationpropertiesPanel, 'numeric');
            app.tps1emkmEditField.Value = 20;
            app.tps2emkmEditFieldLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps2emkmEditFieldLabel.Text = 'tps2 (em km)';
            app.tps2emkmEditField = uieditfield(app.SimulationpropertiesPanel, 'numeric');
            app.tps2emkmEditField.Value = 20;
            app.LaunchTPSpowerOPT_2 = uibutton(app.SimulationpropertiesPanel, 'push');
            app.LaunchTPSpowerOPT_2.ButtonPushedFcn = createCallbackFcn(app, @LaunchTPSpowerOPT_2ButtonPushed, true);
            app.LaunchTPSpowerOPT_2.Text = 'UPDATE simulation properties';
            app.tps_clusterSwitchLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps_clusterSwitchLabel.Text = 'tps_cluster';
            app.tps_clusterSwitch = uiswitch(app.SimulationpropertiesPanel, 'slider');
            app.tps_clusterSwitch.Items = {'random', 'fixed'};
            app.tps_clusterSwitch.Value = 'random';
            app.tps1ListBoxLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps1ListBoxLabel.Text = 'tps1';
            app.tps1_clusterListBox = uilistbox(app.SimulationpropertiesPanel);
            app.tps1_clusterListBox.Items = {'dense', 'sparse'};
            app.tps1_clusterListBox.Value = 'dense';
            app.tps2ListBoxLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps2ListBoxLabel.Text = 'tps2';
            app.tps2_clusterListBox = uilistbox(app.SimulationpropertiesPanel);
            app.tps2_clusterListBox.Items = {'dense', 'sparse'};
            app.tps2_clusterListBox.Value = 'dense';
            app.tps_lengthSwitchLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps_lengthSwitchLabel.Text = 'tps_length';
            app.tps_lengthSwitch = uiswitch(app.SimulationpropertiesPanel, 'slider');
            app.tps_lengthSwitch.Items = {'random', 'fixed'};
            app.tps_lengthSwitch.Value = 'random';
            app.tps106EditFieldLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps106EditFieldLabel.Text = 'tps1 [0..6]';
            app.tps1_numtrainsEditField = uieditfield(app.SimulationpropertiesPanel, 'numeric');
            app.tps1_numtrainsEditField.Value = 1;
            app.tps206EditFieldLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps206EditFieldLabel.Text = 'tps2 [0..6]';
            app.tps2_numtrainsEditField = uieditfield(app.SimulationpropertiesPanel, 'numeric');
            app.tps2_numtrainsEditField.Value = 1;
            app.number_trainsSwitchLabel = uilabel(app.SimulationpropertiesPanel);
            app.number_trainsSwitchLabel.Text = 'number_trains';
            app.number_trainsSwitch = uiswitch(app.SimulationpropertiesPanel, 'slider');
            app.number_trainsSwitch.Items = {'random', 'fixed'};
            app.number_trainsSwitch.Value = 'random';
            app.train_powerSwitchLabel = uilabel(app.SimulationpropertiesPanel);
            app.train_powerSwitchLabel.Text = 'train_power';
            app.train_powerSwitch = uiswitch(app.SimulationpropertiesPanel, 'slider');
            app.train_powerSwitch.Items = {'random', 'fixed'};
            app.train_powerSwitch.Value = 'random';
            app.tps1pw1pwNLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps1pw1pwNLabel.Text = 'tps1 [pw1 pwN]';
            app.tps1_trainpowerEditField = uieditfield(app.SimulationpropertiesPanel, 'text');
            app.tps1_trainpowerEditField.Value = '[1]';
            app.tps2pw1pw2Label = uilabel(app.SimulationpropertiesPanel);
            app.tps2pw1pw2Label.Text = 'tps2 [pw1 pw2]';
            app.tps2_trainpowerEditField = uieditfield(app.SimulationpropertiesPanel, 'text');
            app.tps2_trainpowerEditField.Value = '[1]';
            app.train_pfSwitchLabel = uilabel(app.SimulationpropertiesPanel);
            app.train_pfSwitchLabel.Text = 'train_pf';
            app.train_pfSwitch = uiswitch(app.SimulationpropertiesPanel, 'slider');
            app.train_pfSwitch.Items = {'random', 'fixed'};
            app.train_pfSwitch.Value = 'random';
            app.tps1pf1pfNLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps1pf1pfNLabel.Text = 'tps1 [pf1 pfN]';
            app.tps1_trainpfEditField = uieditfield(app.SimulationpropertiesPanel, 'text');
            app.tps1_trainpfEditField.Value = '[0.98]';
            app.tps2pf1pf2Label = uilabel(app.SimulationpropertiesPanel);
            app.tps2pf1pf2Label.Text = 'tps2 [pf1 pf2]';
            app.tps2_trainpfEditField = uieditfield(app.SimulationpropertiesPanel, 'text');
            app.tps2_trainpfEditField.Value = '[0.98]';
            app.train_posSwitchLabel = uilabel(app.SimulationpropertiesPanel);
            app.train_posSwitchLabel.Text = 'train_pos';
            app.train_posSwitch = uiswitch(app.SimulationpropertiesPanel, 'slider');
            app.train_posSwitch.Items = {'random', 'fixed'};
            app.train_posSwitch.Value = 'random';
            app.tps1pos1posNLabel = uilabel(app.SimulationpropertiesPanel);
            app.tps1pos1posNLabel.Text = 'tps1 [pos1 posN]';
            app.tps1_trainposEditField = uieditfield(app.SimulationpropertiesPanel, 'text');
            app.tps1_trainposEditField.Value = '[0.5]';
            app.tps2pos1pos2Label = uilabel(app.SimulationpropertiesPanel);
            app.tps2pos1pos2Label.Text = 'tps2 [pos1 pos2]';
            app.tps2_trainposEditField = uieditfield(app.SimulationpropertiesPanel, 'text');
            app.tps2_trainposEditField.Value = '[0.5]';
            app.NOTEpowerinMWpfbetween05and1posbetween0and1Label = uilabel(app.SimulationpropertiesPanel);
            app.NOTEpowerinMWpfbetween05and1posbetween0and1Label.Text = 'NOTE: power in MW; pf between 0.5 and 1; pos between 0 and 1';
            app.ElectricdiagramTab = uitab(app.TabGroup);
            app.ElectricdiagramTab.Title = 'Electric diagram';
            app.ElectricDiagramFig = uiaxes(app.ElectricdiagramTab);
            xlabel(app.ElectricDiagramFig, 'X')
            ylabel(app.ElectricDiagramFig, 'Y')
            app.ElectricDiagramFig.XLim = [0 100];
            app.ElectricDiagramFig.YLim = [0 200];
            app.NOTEhereisplottedthelastscenariosimulatedLabel = uilabel(app.ElectricdiagramTab);
            app.NOTEhereisplottedthelastscenariosimulatedLabel.Text = 'NOTE: here is plotted the last scenario simulated';
            app.ResultsAnalysisTab = uitab(app.TabGroup);
            app.ResultsAnalysisTab.Title = 'Results Analysis';
            app.InputparametersStatisticalAnalysisPanel = uipanel(app.ResultsAnalysisTab);
            app.InputparametersStatisticalAnalysisPanel.Title = 'Input parameters: Statistical Analysis';
            app.typeListBoxLabel = uilabel(app.InputparametersStatisticalAnalysisPanel);
            app.typeListBoxLabel.Text = 'type';
            app.statistic_analysis_typeListBox = uilistbox(app.InputparametersStatisticalAnalysisPanel);
            app.statistic_analysis_typeListBox.Items = {'all', 'cluster', 'train_position', 'train_power', 'train_pf'};
            app.statistic_analysis_typeListBox.Value = 'all';
            app.PerformStatisticalAnalysisButton = uibutton(app.InputparametersStatisticalAnalysisPanel, 'push');
            app.PerformStatisticalAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformStatisticalAnalysisButtonPushed, true);
            app.PerformStatisticalAnalysisButton.Text = 'Perform Statistical Analysis';
            app.OutputparametersPTDresultsAnalysisPanel = uipanel(app.ResultsAnalysisTab);
            app.OutputparametersPTDresultsAnalysisPanel.Title = 'Output parameters: PTD results Analysis';
            app.typeListBoxLabel_2 = uilabel(app.OutputparametersPTDresultsAnalysisPanel);
            app.typeListBoxLabel_2.Text = 'type';
            app.ptd_analysis_typeListBox = uilistbox(app.OutputparametersPTDresultsAnalysisPanel);
            app.ptd_analysis_typeListBox.Items = {'all', 'cluster', 'metrics', 'analysis_1', 'nz_voltage', 'tps_power_before_after'};
            app.ptd_analysis_typeListBox.Value = 'all';
            app.PerformResultsAnalysisButton = uibutton(app.OutputparametersPTDresultsAnalysisPanel, 'push');
            app.PerformResultsAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformResultsAnalysisButtonPushed, true);
            app.PerformResultsAnalysisButton.Text = 'Perform Results Analysis';
            app.SimulationclustersPanel = uipanel(app.ResultsAnalysisTab);
            app.SimulationclustersPanel.Title = 'Simulation clusters';
            app.TPSlengthListBox_2Label = uilabel(app.SimulationclustersPanel);
            app.TPSlengthListBox_2Label.Text = 'TPS length';
            app.TPSlengthListBox_2 = uilistbox(app.SimulationclustersPanel);
            app.TPSlengthListBox_2.Items = {'all_tps_lengths', 'long-long', 'long-short', 'short-short', 'long-short2', 'short-long2'};
            app.TPSlengthListBox_2.Value = 'all_tps_lengths';
            app.TPStypeclusterListBox_2Label = uilabel(app.SimulationclustersPanel);
            app.TPStypeclusterListBox_2Label.Text = 'TPS type cluster';
            app.TPStypeclusterListBox_2 = uilistbox(app.SimulationclustersPanel);
            app.TPStypeclusterListBox_2.Items = {'all_tps_clusters', 'dense-dense', 'dense-sparse', 'sparse-sparse', 'dense-sparse2', 'sparse-dense2'};
            app.TPStypeclusterListBox_2.Value = 'all_tps_clusters';
            app.UpdateclusterButton_2 = uibutton(app.SimulationclustersPanel, 'push');
            app.UpdateclusterButton_2.Text = 'Update cluster';
            app.objectsSwitch_2Label = uilabel(app.SimulationclustersPanel);
            app.objectsSwitch_2Label.Text = 'objects';
            app.objectsSwitch_2 = uiswitch(app.SimulationclustersPanel, 'slider');
            app.objectsSwitch_2.Items = {'all', 'clustered'};
            app.objectsSwitch_2.Value = 'all';
            app.underconstructionLabel = uilabel(app.ResultsAnalysisTab);
            app.underconstructionLabel.Text = 'under construction';
            app.ElectricModelParametersTab = uitab(app.TabGroup);
            app.ElectricModelParametersTab.Title = 'Electric Model Parameters';
            app.TPScablePanel = uipanel(app.ElectricModelParametersTab);
            app.TPScablePanel.Title = 'TPS "cable"';
            app.ResistanceOhmkmEditFieldLabel = uilabel(app.TPScablePanel);
            app.ResistanceOhmkmEditFieldLabel.Text = 'Resistance (Ohm/km)';
            app.ResistanceOhmkmEditField = uieditfield(app.TPScablePanel, 'numeric');
            app.ResistanceOhmkmEditField.Value = 0.055;
            app.ReactanceHkmEditFieldLabel = uilabel(app.TPScablePanel);
            app.ReactanceHkmEditFieldLabel.Text = 'Reactance (H/km)';
            app.ReactanceHkmEditField = uieditfield(app.TPScablePanel, 'numeric');
            app.ReactanceHkmEditField.Value = 0.00022;
            app.CapacitanceFkmEditFieldLabel = uilabel(app.TPScablePanel);
            app.CapacitanceFkmEditFieldLabel.Text = 'Capacitance (F/km)';
            app.CapacitanceFkmEditField = uieditfield(app.TPScablePanel, 'numeric');
            app.CapacitanceFkmEditField.Value = 1e-06;
            app.cabledistancekmEditFieldLabel = uilabel(app.TPScablePanel);
            app.cabledistancekmEditFieldLabel.Text = 'cable distance (km)';
            app.cabledistancekmEditField = uieditfield(app.TPScablePanel, 'numeric');
            app.cabledistancekmEditField.Value = 0.5;
            app.TPScatenaryPanel = uipanel(app.ElectricModelParametersTab);
            app.TPScatenaryPanel.Title = 'TPS catenary';
            app.ResistanceOhmkmEditField_2Label = uilabel(app.TPScatenaryPanel);
            app.ResistanceOhmkmEditField_2Label.Text = 'Resistance (Ohm/km)';
            app.ResistanceOhmkmEditField_2 = uieditfield(app.TPScatenaryPanel, 'numeric');
            app.ResistanceOhmkmEditField_2.Value = 0.15;
            app.ReactanceHkmEditField_2Label = uilabel(app.TPScatenaryPanel);
            app.ReactanceHkmEditField_2Label.Text = 'Reactance (H/km)';
            app.ReactanceHkmEditField_2 = uieditfield(app.TPScatenaryPanel, 'numeric');
            app.ReactanceHkmEditField_2.Value = 0.00143;
            app.CapacitanceFkmEditField_2Label = uilabel(app.TPScatenaryPanel);
            app.CapacitanceFkmEditField_2Label.Text = 'Capacitance (F/km)';
            app.CapacitanceFkmEditField_2 = uieditfield(app.TPScatenaryPanel, 'numeric');
            app.CapacitanceFkmEditField_2.Value = 1.24e-08;
            app.PTDtransformerinverterPanel = uipanel(app.ElectricModelParametersTab);
            app.PTDtransformerinverterPanel.Title = 'PTD: transformer + inverter';
            app.TransformerXREditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.TransformerXREditFieldLabel.Text = 'Transformer X/R';
            app.TransformerXREditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.TransformerXREditField.Value = 2;
            app.NominalPowerMWEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.NominalPowerMWEditFieldLabel.Text = 'Nominal Power (MW)';
            app.NominalPowerMWEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.NominalPowerMWEditField.Value = 12.5;
            app.TransformerironlossEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.TransformerironlossEditFieldLabel.Text = 'Transformer iron loss (%)';
            app.TransformerironlossEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.TransformerironlossEditField.Value = 10;
            app.TransformertotallossEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.TransformertotallossEditFieldLabel.Text = 'Transformer total loss (%)';
            app.TransformertotallossEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.TransformertotallossEditField.Value = 2;
            app.TransformerconductionnominallossesMWEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.TransformerconductionnominallossesMWEditFieldLabel.Text = 'Transformer conduction nominal losses (MW)';
            app.TransformerconductionnominallossesMWEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.TransformerconductionnominallossesMWEditField.Value = 0.225;
            app.TransformerpermanentlossesMWEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.TransformerpermanentlossesMWEditFieldLabel.Text = 'Transformer permanent losses (MW)';
            app.TransformerpermanentlossesMWEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.TransformerpermanentlossesMWEditField.Value = 0.025;
            app.inverterswitchinglossEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.inverterswitchinglossEditFieldLabel.Text = 'inverter switching loss (%)';
            app.inverterswitchinglossEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.inverterswitchinglossEditField.Value = 30;
            app.invertertotallossEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.invertertotallossEditFieldLabel.Text = 'inverter total loss (%)';
            app.invertertotallossEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.invertertotallossEditField.Value = 1;
            app.recalculateButton = uibutton(app.PTDtransformerinverterPanel, 'push');
            app.recalculateButton.ButtonPushedFcn = createCallbackFcn(app, @recalculateButtonPushed, true);
            app.recalculateButton.Text = 'recalculate';
            app.InverterconductionnominallossesMWEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.InverterconductionnominallossesMWEditFieldLabel.Text = 'Inverter conduction nominal losses (MW)';
            app.InverterconductionnominallossesMWEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.InverterconductionnominallossesMWEditField.Value = 0.0875;
            app.InverterpermanentlossesMWEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.InverterpermanentlossesMWEditFieldLabel.Text = 'Inverter permanent losses (MW)';
            app.InverterpermanentlossesMWEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.InverterpermanentlossesMWEditField.Value = 0.0375;
            app.bus100InvertertransfpermanentlossesMWEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.bus100InvertertransfpermanentlossesMWEditFieldLabel.Text = 'bus100: Inverter +transf. permanent losses (MW)';
            app.bus100InvertertransfpermanentlossesMWEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.bus100InvertertransfpermanentlossesMWEditField.Value = 0.0625;
            app.TransformerResistanceOhmEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.TransformerResistanceOhmEditFieldLabel.Text = 'Transformer Resistance (Ohm)';
            app.TransformerResistanceOhmEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.TransformerResistanceOhmEditField.Value = 1.089;
            app.TransformerReactancejOhmEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.TransformerReactancejOhmEditFieldLabel.Text = 'Transformer Reactance (jOhm)';
            app.TransformerReactancejOhmEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.TransformerReactancejOhmEditField.Value = 2.178;
            app.NominalCurrentAEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.NominalCurrentAEditFieldLabel.Text = 'Nominal Current (A)';
            app.NominalCurrentAEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.NominalCurrentAEditField.Value = 454;
            app.InverterResistanceOhmEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.InverterResistanceOhmEditFieldLabel.Text = 'Inverter Resistance (Ohm)';
            app.InverterResistanceOhmEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.InverterResistanceOhmEditField.Value = 0.423;
            app.InverterReactancejOhmEditFieldLabel = uilabel(app.PTDtransformerinverterPanel);
            app.InverterReactancejOhmEditFieldLabel.Text = 'Inverter Reactance (jOhm)';
            app.InverterReactancejOhmEditField = uieditfield(app.PTDtransformerinverterPanel, 'numeric');
            app.Button = uibutton(app.PTDtransformerinverterPanel, 'push');
            app.Button.Icon = 'icon_ptd.png';
            app.Button.Text = '';
            app.TPSnominalvoltagePanel = uipanel(app.ElectricModelParametersTab);
            app.TPSnominalvoltagePanel.Title = 'TPS nominal voltage';
            app.VoltagekVEditFieldLabel = uilabel(app.TPSnominalvoltagePanel);
            app.VoltagekVEditFieldLabel.Text = 'Voltage (kV)';
            app.VoltagekVEditField = uieditfield(app.TPSnominalvoltagePanel, 'numeric');
            app.VoltagekVEditField.Value = 27.5;
            app.Updateallelectric_modelparametersButton = uibutton(app.ElectricModelParametersTab, 'push');
            app.Updateallelectric_modelparametersButton.ButtonPushedFcn = createCallbackFcn(app, @Updateallelectric_modelparametersButtonPushed, true);
            app.Updateallelectric_modelparametersButton.Text = 'Update all electric_model parameters';
            app.PerunitPanel = uipanel(app.ElectricModelParametersTab);
            app.PerunitPanel.Title = 'Per-unit';
            app.SbaseMVAEditFieldLabel = uilabel(app.PerunitPanel);
            app.SbaseMVAEditFieldLabel.Text = 'Sbase (MVA)';
            app.SbaseMVAEditField = uieditfield(app.PerunitPanel, 'numeric');
            app.SbaseMVAEditField.Value = 1000;
            app.VbasekVEditFieldLabel = uilabel(app.PerunitPanel);
            app.VbasekVEditFieldLabel.Text = 'Vbase (kV)';
            app.VbasekVEditField = uieditfield(app.PerunitPanel, 'numeric');
            app.VbasekVEditField.Value = 27.5;
            app.IbaseAEditFieldLabel = uilabel(app.PerunitPanel);
            app.IbaseAEditFieldLabel.Text = 'Ibase (A)';
            app.IbaseAEditField = uieditfield(app.PerunitPanel, 'numeric');
            app.IbaseAEditField.Value = 36364;
            app.ZbaseOhmEditFieldLabel = uilabel(app.PerunitPanel);
            app.ZbaseOhmEditFieldLabel.Text = 'Zbase (Ohm)';
            app.ZbaseOhmEditField = uieditfield(app.PerunitPanel, 'numeric');
            app.ZbaseOhmEditField.Value = 0.7563;
            app.updateButton = uibutton(app.ElectricModelParametersTab, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.Monte_carloperformanceTab = uitab(app.TabGroup);
            app.Monte_carloperformanceTab.Title = 'Monte_carlo performance';
            app.launch_MCP = uibutton(app.Monte_carloperformanceTab, 'push');
            app.launch_MCP.ButtonPushedFcn = createCallbackFcn(app, @launch_MCPButtonPushed, true);
            app.launch_MCP.Text = 'Run MCP';
            app.NumberofiterationsEditField_2Label_2 = uilabel(app.Monte_carloperformanceTab);
            app.NumberofiterationsEditField_2Label_2.Text = 'Number of iterations';
            app.Numberofiterations_MCP = uieditfield(app.Monte_carloperformanceTab, 'numeric');
            app.Numberofiterations_MCP.Value = 1000;
            app.GaugeLabel = uilabel(app.Monte_carloperformanceTab);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.Monte_carloperformanceTab, 'linear');
            app.Gauge.Limits = [0 10];
            app.Gauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes = uiaxes(app.Monte_carloperformanceTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NumberofrunsLabel = uilabel(app.Monte_carloperformanceTab);
            app.NumberofrunsLabel.Text = 'Number of runs';
            app.Numberoruns_MCP = uieditfield(app.Monte_carloperformanceTab, 'numeric');
            app.Numberoruns_MCP.Value = 10;
            app.updateButton_2 = uibutton(app.Monte_carloperformanceTab, 'push');
            app.updateButton_2.ButtonPushedFcn = createCallbackFcn(app, @updateButton_2Pushed, true);
            app.updateButton_2.Text = 'update';
            app.ConsoleTextArea_2Label = uilabel(app.Monte_carloperformanceTab);
            app.ConsoleTextArea_2Label.Text = 'Console';
            app.Console_metrics_area = uitextarea(app.Monte_carloperformanceTab);
            app.MonteCarlonumberofsimulationsPanel = uipanel(app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure);
            app.MonteCarlonumberofsimulationsPanel.Title = 'Monte Carlo: number of simulations';
            app.NumberofiterationsEditField_2Label = uilabel(app.MonteCarlonumberofsimulationsPanel);
            app.NumberofiterationsEditField_2Label.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.MonteCarlonumberofsimulationsPanel, 'numeric');
            app.NumberofiterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofiterationsEditFieldValueChanged, true);
            app.NumberofiterationsEditField.Value = 1000;
            app.RUNOPTIMIZATIONPanel = uipanel(app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure);
            app.RUNOPTIMIZATIONPanel.Title = 'RUN OPTIMIZATION';
            app.LaunchTPSpowerOPT = uibutton(app.RUNOPTIMIZATIONPanel, 'push');
            app.LaunchTPSpowerOPT.ButtonPushedFcn = createCallbackFcn(app, @LaunchRunOtimizationPushed, true);
            app.LaunchTPSpowerOPT.Text = 'Run Compensation';
            app.type_of_optimizationLabel = uilabel(app.RUNOPTIMIZATIONPanel);
            app.type_of_optimizationLabel.Text = 'type_of_optimization';
            app.type_of_optimizationListBox = uilistbox(app.RUNOPTIMIZATIONPanel);
            app.type_of_optimizationListBox.Items = {'tps_power_optimization', 'power_losses_optimization', 'nz_voltage_optimization'};
            app.type_of_optimizationListBox.Value = 'tps_power_optimization';
            app.HELPPanel = uipanel(app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure);
            app.HELPPanel.Title = 'HELP';
            app.tps_power_optimizationPanel = uipanel(app.HELPPanel);
            app.tps_power_optimizationPanel.Title = '''tps_power_optimization''';
            app.ObjectivethepowerofbothTPSisbalancedLabel = uilabel(app.tps_power_optimizationPanel);
            app.ObjectivethepowerofbothTPSisbalancedLabel.Text = 'Objective:the power of both TPS is balanced';
            app.power_losses_optimizationPanel = uipanel(app.HELPPanel);
            app.power_losses_optimizationPanel.Title = '''power_losses_optimization''';
            app.ObjectivethepowerofbothTPSisbalancedLabel_2 = uilabel(app.power_losses_optimizationPanel);
            app.ObjectivethepowerofbothTPSisbalancedLabel_2.Text = {'Objective:the power of both TPS is balanced;'; 'Then, the losses are minimized'};
            app.nz_voltage_optimizationPanel = uipanel(app.HELPPanel);
            app.nz_voltage_optimizationPanel.Title = '''nz_voltage_optimization''';
            app.ObjectivethephasevoltageinNZisbalancedLabel = uilabel(app.nz_voltage_optimizationPanel);
            app.ObjectivethephasevoltageinNZisbalancedLabel.Text = 'Objective:the phase voltage in NZ is balanced';
            app.ExecutionpropertiesPanel = uipanel(app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure);
            app.ExecutionpropertiesPanel.Title = 'Execution properties';
            app.parallelismSwitchLabel = uilabel(app.ExecutionpropertiesPanel);
            app.parallelismSwitchLabel.Text = 'parallelism';
            app.parallelismSwitch = uiswitch(app.ExecutionpropertiesPanel, 'slider');
            app.parallelismSwitch.Items = {'enabled', 'disabled'};
            app.parallelismSwitch.Value = 'enabled';
            app.closeallButton = uibutton(app.ExecutionpropertiesPanel, 'push');
            app.closeallButton.ButtonPushedFcn = createCallbackFcn(app, @closeallButtonPushed, true);
            app.closeallButton.Text = 'close all';
            app.monitor_posEditFieldLabel = uilabel(app.ExecutionpropertiesPanel);
            app.monitor_posEditFieldLabel.Text = 'monitor_pos';
            app.monitor_posEditField = uieditfield(app.ExecutionpropertiesPanel, 'numeric');
            app.monitor_posEditField.ValueChangedFcn = createCallbackFcn(app, @monitor_posEditFieldValueChanged, true);
            app.monitor_posEditField.Value = 1980;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_montecarlo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MONTECARLOevaluationforrailwayPowerTransferDeviceUIFigure)
        end
    end
end